classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Caocao             matlab.ui.control.Image
        Machao             matlab.ui.control.Image
        Huangzhong         matlab.ui.control.Image
        Zhangfei           matlab.ui.control.Image
        Guanyu             matlab.ui.control.Image
        Zhaoyun            matlab.ui.control.Image
        zu1                matlab.ui.control.Image
        zu2                matlab.ui.control.Image
        zu3                matlab.ui.control.Image
        zu4                matlab.ui.control.Image
        LButton            matlab.ui.control.Button
        RButton            matlab.ui.control.Button
        DButton            matlab.ui.control.Button
        UButton            matlab.ui.control.Button
        selected           matlab.ui.control.Image
        levelListBoxLabel  matlab.ui.control.Label
        level              matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        Property % Description
    end
    
    methods (Access = private)
        
        function func(app,item)
           app.LButton.Enable = 'on';
           app.RButton.Enable = 'on';
           app.UButton.Enable = 'on';
           app.DButton.Enable = 'on';
           app.selected.Position = item.Position;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Caocao
        function CaocaoClicked(app, event)
           func(app,app.Caocao)
        end

        % Callback function
        function ButtonPushed(app, event)
            app.selected.Position(1) = app.selected.Position(1) - 100; 
            app.Caocao.Position = app.selected.Position;
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            app.selected.Position(1) = app.selected.Position(1) - 100; 
            app.Caocao.Position = app.selected.Position;
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            app.selected.Position(1) = app.selected.Position(1) + 100; 
            app.Caocao.Position = app.selected.Position;
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.selected.Position(2) = app.selected.Position(2) - 100; 
            app.Caocao.Position = app.selected.Position;
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            app.selected.Position(2) = app.selected.Position(2) + 100; 
            app.Caocao.Position = app.selected.Position;
        end

        % Image clicked function: zu1
        function zuImageClicked(app, event)
           app.LButton.Enable = 'on';
           app.RButton.Enable = 'on';
           app.UButton.Enable = 'on';
           app.DButton.Enable = 'on';
           app.selected.Position = app.zu1.Position;
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Caocao = uiimage(app.UIFigure);
            app.Caocao.ImageClickedFcn = createCallbackFcn(app, @CaocaoClicked, true);
            app.Machao = uiimage(app.UIFigure);
            app.Huangzhong = uiimage(app.UIFigure);
            app.Zhangfei = uiimage(app.UIFigure);
            app.Guanyu = uiimage(app.UIFigure);
            app.Zhaoyun = uiimage(app.UIFigure);
            app.zu1 = uiimage(app.UIFigure);
            app.zu1.ImageClickedFcn = createCallbackFcn(app, @zuImageClicked, true);
            app.zu2 = uiimage(app.UIFigure);
            app.zu3 = uiimage(app.UIFigure);
            app.zu4 = uiimage(app.UIFigure);
            app.LButton = uibutton(app.UIFigure, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Enable = 'off';
            app.LButton.Text = 'L';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Enable = 'off';
            app.RButton.Text = 'R';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Enable = 'off';
            app.DButton.Text = 'D';
            app.UButton = uibutton(app.UIFigure, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Enable = 'off';
            app.UButton.Text = 'U';
            app.selected = uiimage(app.UIFigure);
            app.selected.Visible = 'off';
            app.levelListBoxLabel = uilabel(app.UIFigure);
            app.levelListBoxLabel.Text = 'level';
            app.level = uilistbox(app.UIFigure);
            app.level.Items = {'横刀立马', ''};
            app.level.Value = '横刀立马';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end