classdef Elevator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Button_L_3           matlab.ui.control.Button
        Button_L_2           matlab.ui.control.Button
        Button_L_1           matlab.ui.control.Button
        Button_L_Close       matlab.ui.control.Button
        Button_L_Open        matlab.ui.control.Button
        CurrentFloorPanel_L  matlab.ui.container.Panel
        Label_L_Floor        matlab.ui.control.Label
        Label_L_Dir          matlab.ui.control.Label
        Button_R_3           matlab.ui.control.Button
        Button_R_2           matlab.ui.control.Button
        Button_R_1           matlab.ui.control.Button
        Button_R_Close       matlab.ui.control.Button
        Button_R_Open        matlab.ui.control.Button
        CurrentFloorPanel_R  matlab.ui.container.Panel
        Label_R_Floor        matlab.ui.control.Label
        Label_R_Dir          matlab.ui.control.Label
        Lamp_L_2             matlab.ui.control.Lamp
        Lamp_L_1             matlab.ui.control.Lamp
        Lamp_L_Open          matlab.ui.control.Lamp
        Lamp_L_Close         matlab.ui.control.Lamp
        Lamp_R_2             matlab.ui.control.Lamp
        Lamp_R_3             matlab.ui.control.Lamp
        Lamp_R_Close         matlab.ui.control.Lamp
        Lamp_R_Open          matlab.ui.control.Lamp
        Lamp_R_1             matlab.ui.control.Lamp
        Lamp_L_3             matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        elevatorProcessor % Process the two elevators
    end
    
    methods (Access = private)
        
        function displayMessage_L_Floor(app,Message)
            app.Label_L_Floor.Text=Message;
        end
        function displayMessage_L_Dir(app,Message)
            app.Label_L_Dir.Text=Message;
        end
        function displayMessage_R_Floor(app,Message)
            app.Label_R_Floor.Text=Message;
        end
        function displayMessage_R_Dir(app,Message)
            app.Label_R_Dir.Text=Message;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_L_1
        function Button_L_1Pushed(app, event)
            app.Lamp_L_1.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_L_2
        function Button_L_2Pushed(app, event)
            app.Lamp_L_2.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_L_3
        function Button_L_3Pushed(app, event)
            app.Lamp_L_3.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_L_Close
        function Button_L_ClosePushed(app, event)
            app.Lamp_L_Close.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_L_Open
        function Button_L_OpenPushed(app, event)
            app.Lamp_L_Open.Color=[0.0,1.0,0.0];
            pause(2)
            app.Lamp_L_Open.Color=[0.9,0.9,0.9];
        end

        % Button pushed function: Button_R_1
        function Button_R_1Pushed(app, event)
            app.Lamp_R_1.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_R_2
        function Button_R_2Pushed(app, event)
            app.Lamp_R_2.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_R_3
        function Button_R_3Pushed(app, event)
            app.Lamp_R_3.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_R_Close
        function Button_R_ClosePushed(app, event)
            app.Lamp_R_Close.Color=[0.0,1.0,0.0];
        end

        % Button pushed function: Button_R_Open
        function Button_R_OpenPushed(app, event)
            app.Lamp_R_Open.Color=[0.0,1.0,0.0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_L_3 = uibutton(app.UIFigure, 'push');
            app.Button_L_3.ButtonPushedFcn = createCallbackFcn(app, @Button_L_3Pushed, true);
            app.Button_L_3.Text = '3';
            app.Button_L_2 = uibutton(app.UIFigure, 'push');
            app.Button_L_2.ButtonPushedFcn = createCallbackFcn(app, @Button_L_2Pushed, true);
            app.Button_L_2.Text = '2';
            app.Button_L_1 = uibutton(app.UIFigure, 'push');
            app.Button_L_1.ButtonPushedFcn = createCallbackFcn(app, @Button_L_1Pushed, true);
            app.Button_L_1.Text = '1';
            app.Button_L_Close = uibutton(app.UIFigure, 'push');
            app.Button_L_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_L_ClosePushed, true);
            app.Button_L_Close.Text = '>|<';
            app.Button_L_Open = uibutton(app.UIFigure, 'push');
            app.Button_L_Open.ButtonPushedFcn = createCallbackFcn(app, @Button_L_OpenPushed, true);
            app.Button_L_Open.Text = '<|>';
            app.CurrentFloorPanel_L = uipanel(app.UIFigure);
            app.CurrentFloorPanel_L.Title = 'Current Floor';
            app.Label_L_Floor = uilabel(app.CurrentFloorPanel_L);
            app.Label_L_Floor.Text = '1';
            app.Label_L_Dir = uilabel(app.CurrentFloorPanel_L);
            app.Label_L_Dir.Text = ' ';
            app.Button_R_3 = uibutton(app.UIFigure, 'push');
            app.Button_R_3.ButtonPushedFcn = createCallbackFcn(app, @Button_R_3Pushed, true);
            app.Button_R_3.Text = '3';
            app.Button_R_2 = uibutton(app.UIFigure, 'push');
            app.Button_R_2.ButtonPushedFcn = createCallbackFcn(app, @Button_R_2Pushed, true);
            app.Button_R_2.Text = '2';
            app.Button_R_1 = uibutton(app.UIFigure, 'push');
            app.Button_R_1.ButtonPushedFcn = createCallbackFcn(app, @Button_R_1Pushed, true);
            app.Button_R_1.Text = '1';
            app.Button_R_Close = uibutton(app.UIFigure, 'push');
            app.Button_R_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_R_ClosePushed, true);
            app.Button_R_Close.Text = '>|<';
            app.Button_R_Open = uibutton(app.UIFigure, 'push');
            app.Button_R_Open.ButtonPushedFcn = createCallbackFcn(app, @Button_R_OpenPushed, true);
            app.Button_R_Open.Text = '<|>';
            app.CurrentFloorPanel_R = uipanel(app.UIFigure);
            app.CurrentFloorPanel_R.Title = 'Current Floor';
            app.Label_R_Floor = uilabel(app.CurrentFloorPanel_R);
            app.Label_R_Floor.Text = '1';
            app.Label_R_Dir = uilabel(app.CurrentFloorPanel_R);
            app.Label_R_Dir.Text = ' ';
            app.Lamp_L_3 = uilamp(app.UIFigure);
            app.Lamp_L_2 = uilamp(app.UIFigure);
            app.Lamp_L_1 = uilamp(app.UIFigure);
            app.Lamp_L_Open = uilamp(app.UIFigure);
            app.Lamp_L_Close = uilamp(app.UIFigure);
            app.Lamp_R_1 = uilamp(app.UIFigure);
            app.Lamp_R_2 = uilamp(app.UIFigure);
            app.Lamp_R_3 = uilamp(app.UIFigure);
            app.Lamp_R_Close = uilamp(app.UIFigure);
            app.Lamp_R_Open = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Elevator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end