classdef Elevator_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image_Door_Close  matlab.ui.control.Image
        Button_1          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_Open       matlab.ui.control.Button
        Button_Close      matlab.ui.control.Button
        FloorPanel        matlab.ui.container.Panel
        Label_Floor       matlab.ui.control.Label
        Label_Direction   matlab.ui.control.Label
        Lamp_1            matlab.ui.control.Lamp
        Lamp_2            matlab.ui.control.Lamp
        Lamp_3            matlab.ui.control.Lamp
        Lamp_Open         matlab.ui.control.Lamp
        Lamp_Close        matlab.ui.control.Lamp
        Image_Door_Open   matlab.ui.control.Image
        HeightLabel       matlab.ui.control.Label
        Height            matlab.ui.control.Label
        ElevatorLabel     matlab.ui.control.Label
        EleNO             matlab.ui.control.Label
    end

    
    properties (Access = public)
        elevatorProcessor 
        elevator
        NO
    end
    
    methods (Access = public)
        
        function displayFloorMessage(app, FloorMessage)
            app.Label_Floor.Text = FloorMessage;
        end
        
        function displayDirectionMessage(app, DirectionMessage)
            app.Label_Direction.Text = DirectionMessage;
        end
        
        function openInsideDoor(app)
            pause(1);
            app.Image_Door_Open.Visible = 'On';
        end
        
        function closeInsideDoor(app)
            pause(1);
            app.Image_Door_Open.Visible = 'Off';
        end
        
        function closeLight(app,floor)
            switch floor
                case 1
                    app.Lamp_1.Color = [0.9,0.9,0.9];
                case 2
                    app.Lamp_2.Color = [0.9,0.9,0.9];
                case 3
                    app.Lamp_3.Color = [0.9,0.9,0.9];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.Lamp_1.Color = [0.0,1.0,0.0];
            height = app.elevator.h;
            if height > 1*app.elevatorProcessor.floorHeight
                direction = -1;
            elseif height == 1*app.elevatorProcessor.floorHeight
                direction = 0;
            else
                direction = 1;
            end
            app.elevator.receiveReq(1,direction);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Lamp_2.Color = [0.0,1.0,0.0];
            height = app.elevator.h;
            if height > 2*app.elevatorProcessor.floorHeight
                direction = -1;
            elseif height == 2*app.elevatorProcessor.floorHeight
                direction = 0;
            else
                direction = 1;
            end
            app.elevator.receiveReq(2,direction);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Lamp_3.Color = [0.0,1.0,0.0];
            height = app.elevator.h;
            if height > 3*app.elevatorProcessor.floorHeight
                direction = -1;
            elseif height == 3*app.elevatorProcessor.floorHeight
                direction = 0;
            else
                direction = 1;
            end
            app.elevator.receiveReq(3,direction)
        end

        % Button pushed function: Button_Open
        function Button_OpenPushed(app, event)
            app.elevator.open = 1;
            app.Lamp_Open.Color = [0.0,1.0,0.0];
        end

        % Button pushed function: Button_Close
        function Button_ClosePushed(app, event)
            app.elevator.close = 1;
            app.Lamp_Close.Color = [0.0,1.0,0.0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image_Door_Close = uiimage(app.UIFigure);
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_Open = uibutton(app.UIFigure, 'push');
            app.Button_Open.ButtonPushedFcn = createCallbackFcn(app, @Button_OpenPushed, true);
            app.Button_Open.Text = '< | >';
            app.Button_Close = uibutton(app.UIFigure, 'push');
            app.Button_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosePushed, true);
            app.Button_Close.Text = '> | <';
            app.FloorPanel = uipanel(app.UIFigure);
            app.FloorPanel.Title = 'Floor';
            app.Label_Floor = uilabel(app.FloorPanel);
            app.Label_Floor.Text = '1';
            app.Label_Direction = uilabel(app.FloorPanel);
            app.Label_Direction.Text = ' ';
            app.Lamp_1 = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_Open = uilamp(app.UIFigure);
            app.Lamp_Close = uilamp(app.UIFigure);
            app.Image_Door_Open = uiimage(app.UIFigure);
            app.Image_Door_Open.Visible = 'off';
            app.HeightLabel = uilabel(app.UIFigure);
            app.HeightLabel.Text = 'Height';
            app.Height = uilabel(app.UIFigure);
            app.Height.Text = '3';
            app.ElevatorLabel = uilabel(app.UIFigure);
            app.ElevatorLabel.Text = 'Elevator';
            app.EleNO = uilabel(app.UIFigure);
            app.EleNO.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Elevator_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end