classdef Floor_2_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UpButton             matlab.ui.control.Button
        LeftElevator_Close   matlab.ui.control.Image
        UpLamp               matlab.ui.control.Lamp
        LeftFloorPanel       matlab.ui.container.Panel
        Label_Left_Floor     matlab.ui.control.Label
        Label_Left_Dir       matlab.ui.control.Label
        RightElevator_Close  matlab.ui.control.Image
        RightFloorPanel      matlab.ui.container.Panel
        Label_Right_Floor    matlab.ui.control.Label
        Label_Right_Dir      matlab.ui.control.Label
        DownButton           matlab.ui.control.Button
        DownLamp             matlab.ui.control.Lamp
        LeftElevator_Open    matlab.ui.control.Image
        RightElevator_Open   matlab.ui.control.Image
        F2Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        elevatorProcessor % processor of managing the elevators and floor panels
    end
    
    methods (Access = public)
        
        function openLeftDoor(app)
            app.LeftElevator_Open.Visible = 'On';
        end
        
        function openRightDoor(app)
            app.RightElevator_Open.Visible = 'On';
        end
        
        function closeLeftDoor(app)
            app.LeftElevator_Open.Visible = 'Off';
        end
        
        function closeRightDoor(app)
            app.RightElevator_Open.Visible = 'Off';
        end
        
        function displayLeftFloor(app, leftFloorMessage)
            app.Label_Left_Floor.Text = leftFloorMessage;
        end
        
        function displayRightFloor(app, rightFloorMessage)
            app.Label_Right_Floor.Text = rightFloorMessage;
        end
        
        function displayLeftDir(app, leftDirMessage)
            app.Label_Left_Dir.Text = leftDirMessage;
        end
        
        function displayRightDir(app, rightDirMessage)
            app.Label_Right_Dir.Text = rightDirMessage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.UpLamp.Color=[0.0,1.0,0.0];
            app.elevatorProcessor.receiveReq(2, 1);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.DownLamp.Color=[0.0,1.0,0.0];
            app.elevatorProcessor.receiveReq(2, -1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '∧';
            app.LeftElevator_Close = uiimage(app.UIFigure);
            app.LeftElevator_Close.ImageSource = 'Floor_close.jpg';
            app.UpLamp = uilamp(app.UIFigure);
            app.LeftFloorPanel = uipanel(app.UIFigure);
            app.LeftFloorPanel.Title = 'Floor';
            app.Label_Left_Floor = uilabel(app.LeftFloorPanel);
            app.Label_Left_Floor.Text = '1';
            app.Label_Left_Dir = uilabel(app.LeftFloorPanel);
            app.Label_Left_Dir.Text = ' ';
            app.RightElevator_Close = uiimage(app.UIFigure);
            app.RightElevator_Close.ImageSource = 'Floor_close.jpg';
            app.RightFloorPanel = uipanel(app.UIFigure);
            app.RightFloorPanel.Title = 'Floor';
            app.Label_Right_Floor = uilabel(app.RightFloorPanel);
            app.Label_Right_Floor.Text = '1';
            app.Label_Right_Dir = uilabel(app.RightFloorPanel);
            app.Label_Right_Dir.Text = ' ';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = '∨';
            app.DownLamp = uilamp(app.UIFigure);
            app.LeftElevator_Open = uiimage(app.UIFigure);
            app.LeftElevator_Open.Visible = 'off';
            app.LeftElevator_Open.ImageSource = 'Floor_open.jpg';
            app.RightElevator_Open = uiimage(app.UIFigure);
            app.RightElevator_Open.Visible = 'off';
            app.RightElevator_Open.ImageSource = 'Floor_open.jpg';
            app.F2Label = uilabel(app.UIFigure);
            app.F2Label.Text = 'F2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Floor_2_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end