classdef Floor_3_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button             matlab.ui.control.Button
        LeftElevator       matlab.ui.control.Image
        Lamp               matlab.ui.control.Lamp
        LeftFloorPanel     matlab.ui.container.Panel
        LeftFloorMessage   matlab.ui.control.Label
        RightElevator      matlab.ui.control.Image
        RightFloorPanel    matlab.ui.container.Panel
        RightFloorMessage  matlab.ui.control.Label
    end

    
    properties (Access = public)
        elevatorProcessor % processor of managing the elevators and floor panels
    end
    
    methods (Access = public)
        
        function openLeftDoor(app)
            app.LeftElevator_Open.Visible = 'On';
        end
        
        function openRightDoor(app)
            app.RightElevator_Open.Visible = 'On';
        end
        
        function closeLeftDoor(app)
            app.LeftElevator_Open.Visible = 'Off';
        end
        
        function closeRightDoor(app)
            app.RightElevator_Open.Visible = 'Off';
        end
        
        function displayLeftFloor(app, leftFloorMessage)
            app.Label_Left_Floor.Text = leftFloorMessage;
        end
        
        function displayRightFloor(app, rightFloorMessage)
            app.Label_Right_Floor.Text = rightFloorMessage;
        end
        
        function displayLeftDir(app, leftDirMessage)
            app.Label_Left_Dir.Text = leftDirMessage;
        end
        
        function displayRightDir(app, rightDirMessage)
            app.Label_Right_Dir.Text = rightDirMessage;
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '∨';
            app.LeftElevator = uiimage(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.LeftFloorPanel = uipanel(app.UIFigure);
            app.LeftFloorPanel.Title = 'Floor';
            app.LeftFloorMessage = uilabel(app.LeftFloorPanel);
            app.LeftFloorMessage.Text = '1';
            app.RightElevator = uiimage(app.UIFigure);
            app.RightFloorPanel = uipanel(app.UIFigure);
            app.RightFloorPanel.Title = 'Floor';
            app.RightFloorMessage = uilabel(app.RightFloorPanel);
            app.RightFloorMessage.Text = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Floor_3_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end