classdef Starter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SelectLabel  matlab.ui.control.Label
        Select       matlab.ui.control.DropDown
        STARTButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        pressed % Description
        level
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Select
        function SelectValueChanged(app, event)
            value = app.Select.Value;
            app.STARTButton.Visible = 'on';
            if(value == char("横刀立马"))
                app.level = 1;
            elseif(value == char("横竖皆将"))
                app.level = 2;
            elseif(value == char("三军联防"))
                app.level = 3;
            elseif(value == char("四路皆兵"))
                app.level = 4;
            elseif(value == char("test"))
                app.level = 5;
            elseif(value == char("前挡后堵"))
                app.level = 6;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.pressed = true;
            if( app.level == 1&&app.pressed == true)
                b = board;
                b.level = 1;
            elseif(app.level == 2&&app.pressed == true)
                b = board;
                b.level = 2;
            elseif(app.level == 3&&app.pressed == true)
                b = board;
                b.level = 3;
            elseif(app.level == 4&&app.pressed == true)
                b = board;
                b.level = 4;
            elseif(app.level == 5&&app.pressed == true)
                b = board;
                b.level = 5;
            elseif(app.level == 6&&app.pressed == true)
                b = board;
                b.level = 6;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectLabel = uilabel(app.UIFigure);
            app.SelectLabel.Text = 'Select';
            app.Select = uidropdown(app.UIFigure);
            app.Select.Items = {'level', '横刀立马', '横竖皆将', '三军联防', '四路皆兵', '前挡后堵', 'test'};
            app.Select.ValueChangedFcn = createCallbackFcn(app, @SelectValueChanged, true);
            app.Select.Value = 'level';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Visible = 'off';
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Starter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end