classdef board < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StartButton  matlab.ui.control.Button
        UButton      matlab.ui.control.Button
        DButton      matlab.ui.control.Button
        RButton      matlab.ui.control.Button
        LButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        Caocao      matlab.ui.control.Image
        Machao      matlab.ui.control.Image
        Huangzhong  matlab.ui.control.Image
        Zhangfei    matlab.ui.control.Image
        Guanyu      matlab.ui.control.Image
        Zhaoyun     matlab.ui.control.Image
        Zu1         matlab.ui.control.Image
        Zu2         matlab.ui.control.Image
        Zu3         matlab.ui.control.Image
        Zu4         matlab.ui.control.Image
        Status
        level
        Mirror
        plist
        Selected
        % Description
    end
    
    methods (Access = public)
        
        function func(app)
            disp("!!!!!!!!!!!!!!!!!!!!!!")
        end
        function createchess(app)
            %Caocao
            
            disp(app.Mirror.mirror);
            caocao = chesstype(app.plist.caocaop(3));
            app.Caocao = uiimage(app.UIFigure);
            app.Caocao.ScaleMethod = 'stretch';
            app.Caocao.Position = [app.plist.caocaop(1)*100,150 + app.plist.caocaop(2)*100,caocao.width,caocao.height];
            app.Caocao.ImageSource = 'caocao.jpg';
            app.Caocao.ImageClickedFcn = createCallbackFcn(app, @CaocaoClicked, true);
            
            
            %Machao
            machao = chesstype(app.plist.machaop(3));
            app.Machao = uiimage(app.UIFigure);
            app.Machao.ScaleMethod = 'stretch';
            if(app.plist.machaop(4)==1)
                app.Machao.Position = [app.plist.machaop(1)*100,150 + app.plist.machaop(2)*100,machao.height,machao.width];
                app.Machao.ImageSource = 'machaoh.jpg';
            elseif(app.plist.machaop(4)==0)
                app.Machao.Position = [app.plist.machaop(1)*100,150 + app.plist.machaop(2)*100,machao.width,machao.height];
                app.Machao.ImageSource = 'machao.jpg';
            end
            
            app.Machao.ImageClickedFcn = createCallbackFcn(app, @MachaoClicked, true);
            
            %Huangzhong
            huangzhong = chesstype(app.plist.huangzhongp(3));
            app.Huangzhong = uiimage(app.UIFigure);
            app.Huangzhong.ScaleMethod = 'stretch';
            if(app.plist.huangzhongp(4)==1)
                app.Huangzhong.Position = [app.plist.huangzhongp(1)*100,150 + app.plist.huangzhongp(2)*100,huangzhong.height,huangzhong.width];
                app.Huangzhong.ImageSource = 'huangzhongh.jpg';
            elseif(app.plist.huangzhongp(4)==0)
                app.Huangzhong.Position = [app.plist.huangzhongp(1)*100,150 + app.plist.huangzhongp(2)*100,huangzhong.width,huangzhong.height];
                app.Huangzhong.ImageSource = 'huangzhong.jpg';
            end
            
            app.Huangzhong.ImageClickedFcn = createCallbackFcn(app, @HuangzhongClicked, true);
            
            %Zhangfei
            zhangfei = chesstype(app.plist.zhangfeip(3));
            app.Zhangfei = uiimage(app.UIFigure);
            app.Zhangfei.ScaleMethod = 'stretch';
            if(app.plist.zhangfeip(4)==1)
                app.Zhangfei.Position = [app.plist.zhangfeip(1)*100,150 + app.plist.zhangfeip(2)*100,zhangfei.height,zhangfei.width];
                app.Zhangfei.ImageSource = 'zhangfeih.jpg';
            elseif(app.plist.zhangfeip(4)==0)
                app.Zhangfei.Position = [app.plist.zhangfeip(1)*100,150 + app.plist.zhangfeip(2)*100,zhangfei.width,zhangfei.height];
                app.Zhangfei.ImageSource = 'zhangfei.jpg';
            end
            
            app.Zhangfei.ImageClickedFcn = createCallbackFcn(app, @ZhangfeiClicked, true);
            
            %Guanyu
            guanyu = chesstype(app.plist.guanyup(3));
            app.Guanyu = uiimage(app.UIFigure);
            app.Guanyu.ScaleMethod = 'stretch';
            if(app.plist.guanyup(4)==1)
                app.Guanyu.Position = [app.plist.guanyup(1)*100,150 + app.plist.guanyup(2)*100,guanyu.height,guanyu.width];
                app.Guanyu.ImageSource = 'guanyuh.jpg';
            elseif(app.plist.guanyup(4)==0)
                app.Guanyu.Position = [app.plist.guanyup(1)*100,150 + app.plist.guanyup(2)*100,guanyu.width,guanyu.height];
                app.Guanyu.ImageSource = 'guanyu.jpg';
            end
            
            app.Guanyu.ImageClickedFcn = createCallbackFcn(app, @GuanyuClicked, true);
            
            %Zhaoyun
            zhaoyun = chesstype(app.plist.zhaoyunp(3));
            app.Zhaoyun = uiimage(app.UIFigure);
            app.Zhaoyun.ScaleMethod = 'stretch';
            if(app.plist.zhaoyunp(4)==1)
                app.Zhaoyun.Position = [app.plist.zhaoyunp(1)*100,150 + app.plist.zhaoyunp(2)*100,zhaoyun.height,zhaoyun.width];
                app.Zhaoyun.ImageSource = 'zhaoyunh.jpg';
            elseif(app.plist.zhaoyunp(4)==0)
                app.Zhaoyun.Position = [app.plist.zhaoyunp(1)*100,150 + app.plist.zhaoyunp(2)*100,zhaoyun.width,zhaoyun.height];
                app.Zhaoyun.ImageSource = 'zhaoyun.jpg';
            end
            
            app.Zhaoyun.ImageClickedFcn = createCallbackFcn(app, @ZhaoyunClicked, true);


            %Zu
            zu1 = chesstype(app.plist.zu1p(3));
            app.Zu1 = uiimage(app.UIFigure);
            app.Zu1.Position = [app.plist.zu1p(1)*100,150 + app.plist.zu1p(2)*100,zu1.width,zu1.height];
            app.Zu1.ImageSource = 'zu.jpg';
            app.Zu1.ImageClickedFcn = createCallbackFcn(app, @Zu1Clicked, true);
            zu2 = chesstype(app.plist.zu2p(3));
            app.Zu2 = uiimage(app.UIFigure);
            app.Zu2.Position = [app.plist.zu2p(1)*100,150 + app.plist.zu2p(2)*100,zu2.width,zu2.height];
            app.Zu2.ImageSource = 'zu.jpg';
            app.Zu2.ImageClickedFcn = createCallbackFcn(app, @Zu2Clicked, true);
            zu3 = chesstype(app.plist.zu3p(3));
            app.Zu3 = uiimage(app.UIFigure);
            app.Zu3.Position = [app.plist.zu3p(1)*100,150 + app.plist.zu3p(2)*100,zu3.width,zu3.height];
            app.Zu3.ImageSource = 'zu.jpg';
            app.Zu3.ImageClickedFcn = createCallbackFcn(app, @Zu3Clicked, true);
            zu4 = chesstype(app.plist.zu4p(3));
            app.Zu4 = uiimage(app.UIFigure);
            app.Zu4.Position = [app.plist.zu4p(1)*100,150 + app.plist.zu4p(2)*100,zu4.width,zu4.height];
            app.Zu4.ImageSource = 'zu.jpg';
            app.Zu4.ImageClickedFcn = createCallbackFcn(app, @Zu4Clicked, true);
            
            Win(app);
        end
        
        function Win(app)
            if(app.Caocao.Position(1)==100&&app.Caocao.Position(2)-150==0)
                run Win;
                
            end
        end
        function CaocaoClicked(app,event)
            position(1) = app.Caocao.Position(1)/100 + 2;
            position(2) = 6 - (app.Caocao.Position(2)-150)/100;
            reset(app)
            caocaopredict(app,position(1),position(2));
            app.Selected = 1;
        end
        function MachaoClicked(app,event)
            position(1) = app.Machao.Position(1)/100 + 2;
            position(2) = 6 - (app.Machao.Position(2)-150)/100;
            who = app.Mirror.mirror(position(1),position(2));
            tmp = Level(app.level);
            horizontal = tmp.positionlist(who,4);
            reset(app)
            fivepredict(app,position(1),position(2),horizontal)
            app.Selected = 2;
        end
        function HuangzhongClicked(app,event)
            position(1) = app.Huangzhong.Position(1)/100 + 2;
            position(2) = 6 - (app.Huangzhong.Position(2)-150)/100;
            who = app.Mirror.mirror(position(1),position(2));
            tmp = Level(app.level);
            horizontal = tmp.positionlist(who,4);
            reset(app)
            fivepredict(app,position(1),position(2),horizontal)
            app.Selected = 3;
        end
        function ZhangfeiClicked(app,event)
            position(1) = app.Zhangfei.Position(1)/100 + 2;
            position(2) = 6 - (app.Zhangfei.Position(2)-150)/100;
            who = app.Mirror.mirror(position(1),position(2));
            tmp = Level(app.level);
            horizontal = tmp.positionlist(who,4);
            reset(app)
            fivepredict(app,position(1),position(2),horizontal)
            app.Selected = 4;
        end
        function GuanyuClicked(app,event)
            position(1) = app.Guanyu.Position(1)/100 + 2;
            position(2) = 6 - (app.Guanyu.Position(2)-150)/100;
            who = app.Mirror.mirror(position(1),position(2));
            tmp = Level(app.level);
            horizontal = tmp.positionlist(who,4);
            reset(app)
            fivepredict(app,position(1),position(2),horizontal)
            app.Selected = 5;
        end
        function ZhaoyunClicked(app,event)
            position(1) = app.Zhaoyun.Position(1)/100 + 2;
            position(2) = 6 - (app.Zhaoyun.Position(2)-150)/100;
            who = app.Mirror.mirror(position(1),position(2));
            tmp = Level(app.level);
            horizontal = tmp.positionlist(who,4);
            reset(app)
            fivepredict(app,position(1),position(2),horizontal);
            app.Selected = 6;
        end
        function Zu1Clicked(app,event)
            position(1) = app.Zu1.Position(1)/100 + 2;
            position(2) = 6 - (app.Zu1.Position(2)-150)/100;
            reset(app)
            predict(app,position(1),position(2));
            app.Selected = 7;
        end
        function Zu2Clicked(app,event)
            position(1) = app.Zu2.Position(1)/100 + 2;
            position(2) = 6 - (app.Zu2.Position(2)-150)/100;
            reset(app)
            predict(app,position(1),position(2));
            app.Selected = 8;
        end
        function Zu3Clicked(app,event)
            position(1) = app.Zu3.Position(1)/100 + 2;
            position(2) = 6 - (app.Zu3.Position(2)-150)/100;
            reset(app)
            predict(app,position(1),position(2));
            app.Selected = 9;
        end
        function Zu4Clicked(app,event)
            position(1) = app.Zu4.Position(1)/100 + 2;
            position(2) = 6 - (app.Zu4.Position(2)-150)/100;
            reset(app);
            predict(app,position(1),position(2));
            app.Selected = 10;
        end
        function reset(app)
            app.UButton.Enable = 'off';
            app.DButton.Enable = 'off';
            app.LButton.Enable = 'off';
            app.RButton.Enable = 'off';
        end
        function predict(app,x,y)
            if(app.Mirror.mirror(x,y-1) == 0)
                app.UButton.Enable = 'on';
            end
            if(app.Mirror.mirror(x,y+1) == 0)
                app.DButton.Enable = 'on';
            end
            if(app.Mirror.mirror(x-1,y) == 0)
                app.LButton.Enable = 'on';
            end
            if(app.Mirror.mirror(x+1,y) == 0)
                app.RButton.Enable = 'on';
            end
        end
        function caocaopredict(app,x,y)
            if(app.Mirror.mirror(x,y-2) == 0&&app.Mirror.mirror(x+1,y-2) == 0)
                app.UButton.Enable = 'on';
            end
            if(app.Mirror.mirror(x,y+1) == 0&&app.Mirror.mirror(x+1,y+1) == 0)
                app.DButton.Enable = 'on';
            end
            if(app.Mirror.mirror(x-1,y) == 0&&app.Mirror.mirror(x-1,y-1) == 0)
                app.LButton.Enable = 'on';
            end
            if(app.Mirror.mirror(x+2,y) == 0&&app.Mirror.mirror(x+2,y-1) == 0)
                app.RButton.Enable = 'on';
            end
        end
        function fivepredict(app,x,y,h)
            if(h==0)
                if(app.Mirror.mirror(x,y-2) == 0)
                    app.UButton.Enable = 'on';
                end
                if(app.Mirror.mirror(x,y+1) == 0)
                    app.DButton.Enable = 'on';
                end
                if(app.Mirror.mirror(x-1,y) == 0&&app.Mirror.mirror(x-1,y-1) == 0)
                    app.LButton.Enable = 'on';
                end
                if(app.Mirror.mirror(x+1,y) == 0&&app.Mirror.mirror(x+1,y-1) == 0)
                    app.RButton.Enable = 'on';
                end
            elseif(h==1)
                if(app.Mirror.mirror(x,y-1) == 0&&app.Mirror.mirror(x+1,y-1) == 0)
                    app.UButton.Enable = 'on';
                end
                if(app.Mirror.mirror(x,y+1) == 0&&app.Mirror.mirror(x+1,y+1) == 0)
                    app.DButton.Enable = 'on';
                end
                if(app.Mirror.mirror(x-1,y) == 0)
                    app.LButton.Enable = 'on';
                end
                if(app.Mirror.mirror(x+2,y) == 0)
                    app.RButton.Enable = 'on';
                end
            end
        end
        function deleteimage(app)
            delete(app.Caocao);
            delete(app.Machao);
            delete(app.Huangzhong);
            delete(app.Zhangfei);
            delete(app.Guanyu);
            delete(app.Zhaoyun);
            delete(app.Zu1);
            delete(app.Zu2);
            delete(app.Zu3);
            delete(app.Zu4);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            func(app);
            app.StartButton.Visible = 'off';
            app.UIFigure.Position = [200,0,400,700];
            app.Status = Level(app.level);
            disp(app.level)
            disp(app.Status.positionlist)
            app.plist = ChessPosition(app.Status);
            app.Mirror = boardmirror(app.Status);
            createchess(app);
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            app.Status.positionlist(app.Selected,2) = app.Status.positionlist(app.Selected,2) + 1;
            app.plist = ChessPosition(app.Status);
            app.Mirror = boardmirror(app.Status);
            deleteimage(app);
            createchess(app);
            reset(app);
            
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.Status.positionlist(app.Selected,2) = app.Status.positionlist(app.Selected,2) - 1;
            app.plist = ChessPosition(app.Status);
            app.Mirror = boardmirror(app.Status);
            deleteimage(app);
            createchess(app);
            reset(app);
            
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            app.Status.positionlist(app.Selected,1) = app.Status.positionlist(app.Selected,1) + 1;
            app.plist = ChessPosition(app.Status);
            app.Mirror = boardmirror(app.Status);
            deleteimage(app);
            createchess(app);
            reset(app);
            
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            app.Status.positionlist(app.Selected,1) = app.Status.positionlist(app.Selected,1) - 1;
            app.plist = ChessPosition(app.Status);
            app.Mirror = boardmirror(app.Status);
            deleteimage(app);
            createchess(app);
            reset(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = {'Start'; ''};
            app.UButton = uibutton(app.UIFigure, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Enable = 'off';
            app.UButton.Text = 'U';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Enable = 'off';
            app.DButton.Text = 'D';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Enable = 'off';
            app.RButton.Text = 'R';
            app.LButton = uibutton(app.UIFigure, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Enable = 'off';
            app.LButton.Text = 'L';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = board

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end