classdef patient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Lamp      matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        YorN
        t
        check
        name1
        name1used
        name2
        name2used
        name3
        name3used
        name4
        name4used
    end
    properties (Access = private)
                physician
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            name = 'name1';
            switch name
                case 'name1'
                    left = app.name1(5);
                    bolus = app.name1(2);
                    [usedhr,usedday,~,app.YorN] = app.check.checkbolus(app.t,app.name1(5),3,1,bolus,app.name1used);
                    app.name1(3) = usedday;
                    app.name1(4) = usedhr;
                    if app.YorN == 1
                        app.Lamp.Color = [0,1,0];
                        app.name1(4) = left-app.name1(1);
                    else
                        app.Lamp.Color = [1,0,0];
                    end
                case 'name2'
                    left = app.name2(5);
                    bolus = app.name2(2);
                    [usedhr,usedday,~,app.YorN] = app.check.checkbolus(app.t,app.name2(5),3,1,bolus,app.name2used);
                    app.name2(3) = usedday;
                    app.name2(4) = usedhr;
                    if app.YorN == 1
                        app.Lamp.Color = [0,1,0];
                        app.name2(5) = left-app.name2(2);
                    else
                        app.Lamp.Color = [1,0,0];
                    end
                case 'name3'
                    left = app.name3(5);
                    bolus = app.name3(2);
                    [usedhr,usedday,~,app.YorN] = checkbolus(app.t,app.name3(5),3,1,bolus,app.name3used);
                    app.name3(3) = usedday;
                    app.name3(4) = usedhr;
                    if app.YorN == 1
                        app.Lamp.Color = [0,1,0];
                        app.name3(5) = left-app.name3(2);
                    else
                        app.Lamp.Color = [1,0,0];
                    end
                case 'name4'
                    left = app.name4(5);
                    bolus = app.name4(2);
                    [usedhr,usedday,~,app.YorN] = checkbolus(app.t,app.name4(5),3,1,bolus,app.name4used);
                    app.name4(3) = usedday;
                    app.name4(4) = usedhr;
                    if app.YorN == 1
                        app.Lamp.Color = [0,1,0];
                        app.name4(5) = left-app.name4(2);
                    else
                        app.Lamp.Color = [1,0,0];
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end