classdef physician < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        name1Menu                       matlab.ui.container.Menu
        name2Menu                       matlab.ui.container.Menu
        name3Menu                       matlab.ui.container.Menu
        name4Menu                       matlab.ui.container.Menu
        limit3Label                     matlab.ui.control.Label
        someotherinformationLabel       matlab.ui.control.Label
        usedmldayTextAreaLabel          matlab.ui.control.Label
        usedmldayTextArea               matlab.ui.control.TextArea
        limit1Label                     matlab.ui.control.Label
        usedmlhrTextAreaLabel           matlab.ui.control.Label
        usedmlhrTextArea                matlab.ui.control.TextArea
        mlminLabel                      matlab.ui.control.Label
        mlshotLabel                     matlab.ui.control.Label
        mlLabel                         matlab.ui.control.Label
        baselinesetting00101EditFieldLabel  matlab.ui.control.Label
        baselinesetting00101EditField   matlab.ui.control.NumericEditField
        addtoEditFieldLabel             matlab.ui.control.Label
        addtoEditField                  matlab.ui.control.NumericEditField
        changeButton                    matlab.ui.control.Button
        changeButton_2                  matlab.ui.control.Button
        baselinemlminLabel              matlab.ui.control.Label
        baselinemlminTextArea           matlab.ui.control.TextArea
        bolusmlshotTextAreaLabel        matlab.ui.control.Label
        bolusmlshotTextArea             matlab.ui.control.TextArea
        addButton                       matlab.ui.control.Button
        medicineleftTextAreaLabel       matlab.ui.control.Label
        medicineleftTextArea            matlab.ui.control.TextArea
        mlLabel_2                       matlab.ui.control.Label
        bolussetting0205EditFieldLabel  matlab.ui.control.Label
        bolussetting0205EditField       matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        t=0
        %change the period to check
        main
        Timer
        name = 'name1'
        name1=[0.01,0.2,0.0,0.0,20]
        name1used=zeros(24,60)
        name2=[0.01,0.2,0.0,0.0,20]
        name2used=zeros(24,60)
        name3=[0.01,0.2,0.0,0.0,20]
        name3used=zeros(24,60)
        name4=[0.01,0.2,0.0,0.0,20]
        name4used=zeros(24,60)
    end
    
    properties (Access = private)
        patient
    end
    methods (Access = public)
        function timerstart(app)
            app.t=app.t+1;
            if app.t==1441
                app.t=0;
                delete(app.Timer);
                start(app.Timer);
            end
            baseline(app); 
        end    
         function baseline(app)
           
            %app.timer = timer('TimerFcn','timeratart(app);', 'Period', 1, 'ExecutionMode', 'fixedDelay', 'TasksToExecute',1440 );
                    %name1
                    [use,usedHour,usedDay,nameused]=checkbaseline(app.t,app.name1(5),3,1,app.name1(1),app.name1used);
                    app.usedmlhrTextArea.Value = usedHour;
                    app.usedmldayTextArea.Value = usedDay;
                    app.name1used = nameused;
                    app.name1(5) = app.name1(5)-use;
                    %name2
                    [use,usedHour,usedDay,nameused]=checkbaseline(app.t,app.name2(5),3,1,app.name2(1),app.name2used);
                    app.usedmlhrTextArea.Value = usedHour;
                    app.usedmldayTextArea.Value = usedDay;
                    app.name2used = nameused;
                    app.name2(5) = app.name2(5)-use;
                    %name3
                    [use,usedHour,usedDay,nameused]=checkbaseline(app.t,app.name3(5),3,1,app.name3(1),app.name3used);
                    app.usedmlhrTextArea.Value = usedHour;
                    app.usedmldayTextArea.Value = usedDay;
                    app.name3used = nameused;
                    app.name3(5) = app.name3(5)-use;
                    %name4
                    [use,usedHour,usedDay,nameused]=checkbaseline(app.t,app.name4(5),3,1,app.name4(1),app.name4used);
                    app.usedmlhrTextArea.Value = usedHour;
                    app.usedmldayTextArea.Value = usedDay;
                    app.name4used = nameused;
                    app.name4(5) = app.name4(5)-use;
            
        end       
    end
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function baseline00101TextAreaValueChanged(app, event)
            
        end

        % Callback function
        function bolussetting0205EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: changeButton
        function changeButtonPushed(app, event)
            baseline = app.baselinemlminTextArea.Value;
            value = app.baselinesetting00101EditField.Value;
            if value >= 0.1
                app.baselinemlminTextArea.Value = baseline;
            elseif value <= 0.01
                app.baselinemlminTextArea.Value = baseline;
            end
            switch app.name
                case 'name1'
                    app.name1(1) = value;
                    app.baselinemlminTextArea.Value = num2str(value);
                case 'name2'
                    app.name2(1) = value;
                    app.baselinemlminTextArea.Value = num2str(value);
                case 'name3'
                    app.name3(1) = value;
                    app.baselinemlminTextArea.Value = num2str(value);
                case 'name4'
                    app.name4(1) = value;
                    app.baselinemlminTextArea.Value = num2str(value);
            end
            
        end

        % Callback function
        function baselinesetting00101EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: changeButton_2
        function changeButton_2Pushed(app, event)
            bolus = app.bolusmlshotTextArea.Value;
            value = app.bolussetting0205EditField.Value;
            if value > 0.51
                app.bolusmlshotTextArea.Value = bolus;
            elseif value < 0.19
                app.bolusmlshotTextArea.Value = bolus;
            end
            switch app.name
                case 'name1'
                    app.name1(2) = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
                case 'name2'
                    app.name2(2) = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
                case 'name3'
                    app.name3(2) = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
                case 'name4'
                    app.name4(2) = value;
                    app.bolusmlshotTextArea.Value = num2str(value);
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            %left = app.medicineleftTextArea.Value;
            value = app.addtoEditField.Value;
            switch app.name
                case 'name1'
                    app.name1(5) = value;
                    app.medicineleftTextArea.Value = num2str(value);
                case 'name2'
                    app.name2(5) = value;
                    app.medicineleftTextArea.Value = num2str(value);
                case 'name3'
                    app.name3(5) = value;
                    app.medicineleftTextArea.Value = num2str(value);
                case 'name4'
                    app.name4(5) = value;
                    app.medicineleftTextArea.Value = num2str(value);
            end
        end

        % Callback function
        function nameDropDownValueChanged(app, event)
            
        end

        % Menu selected function: name1Menu
        function name1MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name1(1));
         app.bolusmlshotTextArea.Value = num2str(app.name1(2));
         app.usedmldayTextArea.Value = num2str(app.name1(3));
         app.usedmlhrTextArea.Value = num2str(app.name1(4));
         app.medicineleftTextArea.Value = num2str(app.name1(5));
         app.TextArea.Value = 'name1';
         app.name = 'name1';
           
        end

        % Menu selected function: name2Menu
        function name2MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name2(1));
         app.bolusmlshotTextArea.Value = num2str(app.name2(2));
         app.usedmldayTextArea.Value = num2str(app.name2(3));
         app.usedmlhrTextArea.Value = num2str(app.name2(4));
         app.medicineleftTextArea.Value = num2str(app.name2(5));
         app.TextArea.Value = 'name2';
         app.name = 'name2';
        end

        % Menu selected function: name3Menu
        function name3MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name3(1));
         app.bolusmlshotTextArea.Value = num2str(app.name3(2));
         app.usedmldayTextArea.Value = num2str(app.name3(3));
         app.usedmlhrTextArea.Value = num2str(app.name3(4));
         app.medicineleftTextArea.Value = num2str(app.name3(5));
         app.TextArea.Value = 'name3';
         app.name = 'name3';
        end

        % Menu selected function: name4Menu
        function name4MenuSelected(app, event)
         app.baselinemlminTextArea.Value = num2str(app.name4(1));
         app.bolusmlshotTextArea.Value = num2str(app.name4(2));
         app.usedmldayTextArea.Value = num2str(app.name4(3));
         app.usedmlhrTextArea.Value = num2str(app.name4(4));
         app.medicineleftTextArea.Value = num2str(app.name4(5));
         app.TextArea.Value = 'name4';
         app.name = 'name4';
        end

        % Callback function
        function addButton_2Pushed(app, event)
            %app.addCustomMenuFcn(UIFigure,)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.name1Menu = uimenu(app.UIFigure);
            app.name1Menu.MenuSelectedFcn = createCallbackFcn(app, @name1MenuSelected, true);
            app.name1Menu.Text = 'name1';
            app.name2Menu = uimenu(app.UIFigure);
            app.name2Menu.MenuSelectedFcn = createCallbackFcn(app, @name2MenuSelected, true);
            app.name2Menu.Text = 'name2';
            app.name3Menu = uimenu(app.UIFigure);
            app.name3Menu.MenuSelectedFcn = createCallbackFcn(app, @name3MenuSelected, true);
            app.name3Menu.Text = 'name3';
            app.name4Menu = uimenu(app.UIFigure);
            app.name4Menu.MenuSelectedFcn = createCallbackFcn(app, @name4MenuSelected, true);
            app.name4Menu.Text = 'name4';
            app.limit3Label = uilabel(app.UIFigure);
            app.limit3Label.Text = '(limit: 3)';
            app.someotherinformationLabel = uilabel(app.UIFigure);
            app.someotherinformationLabel.Text = {'some other information'; '......'};
            app.usedmldayTextAreaLabel = uilabel(app.UIFigure);
            app.usedmldayTextAreaLabel.Text = 'used (ml/day)';
            app.usedmldayTextArea = uitextarea(app.UIFigure);
            app.usedmldayTextArea.Editable = 'off';
            app.usedmldayTextArea.Value = {'0.0'};
            app.limit1Label = uilabel(app.UIFigure);
            app.limit1Label.Text = '(limit: 1)';
            app.usedmlhrTextAreaLabel = uilabel(app.UIFigure);
            app.usedmlhrTextAreaLabel.Text = 'used (ml/hr)';
            app.usedmlhrTextArea = uitextarea(app.UIFigure);
            app.usedmlhrTextArea.Editable = 'off';
            app.usedmlhrTextArea.Value = {'0.0'};
            app.mlminLabel = uilabel(app.UIFigure);
            app.mlminLabel.Text = '(ml/min)';
            app.mlshotLabel = uilabel(app.UIFigure);
            app.mlshotLabel.Text = '(ml/shot)';
            app.mlLabel = uilabel(app.UIFigure);
            app.mlLabel.Text = '(ml)';
            app.baselinesetting00101EditFieldLabel = uilabel(app.UIFigure);
            app.baselinesetting00101EditFieldLabel.Text = 'baseline setting(0.01-0.1)';
            app.baselinesetting00101EditField = uieditfield(app.UIFigure, 'numeric');
            app.baselinesetting00101EditField.Value = 0.01;
            app.addtoEditFieldLabel = uilabel(app.UIFigure);
            app.addtoEditFieldLabel.Text = 'add to';
            app.addtoEditField = uieditfield(app.UIFigure, 'numeric');
            app.addtoEditField.Value = 20;
            app.changeButton = uibutton(app.UIFigure, 'push');
            app.changeButton.ButtonPushedFcn = createCallbackFcn(app, @changeButtonPushed, true);
            app.changeButton.Text = 'change';
            app.changeButton_2 = uibutton(app.UIFigure, 'push');
            app.changeButton_2.ButtonPushedFcn = createCallbackFcn(app, @changeButton_2Pushed, true);
            app.changeButton_2.Text = 'change';
            app.baselinemlminLabel = uilabel(app.UIFigure);
            app.baselinemlminLabel.Text = 'baseline(ml/min)';
            app.baselinemlminTextArea = uitextarea(app.UIFigure);
            app.baselinemlminTextArea.Editable = 'off';
            app.baselinemlminTextArea.Value = {'0.01'};
            app.bolusmlshotTextAreaLabel = uilabel(app.UIFigure);
            app.bolusmlshotTextAreaLabel.Text = 'bolus(ml/shot)';
            app.bolusmlshotTextArea = uitextarea(app.UIFigure);
            app.bolusmlshotTextArea.Editable = 'off';
            app.bolusmlshotTextArea.Value = {'0.2'};
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.medicineleftTextAreaLabel = uilabel(app.UIFigure);
            app.medicineleftTextAreaLabel.Text = 'medicine left:';
            app.medicineleftTextArea = uitextarea(app.UIFigure);
            app.medicineleftTextArea.Editable = 'off';
            app.medicineleftTextArea.Value = {'20'};
            app.mlLabel_2 = uilabel(app.UIFigure);
            app.mlLabel_2.Text = 'ml';
            app.bolussetting0205EditFieldLabel = uilabel(app.UIFigure);
            app.bolussetting0205EditFieldLabel.Text = 'bolus setting(0.2-0.5)';
            app.bolussetting0205EditField = uieditfield(app.UIFigure, 'numeric');
            app.bolussetting0205EditField.Value = 0.2;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = physician

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end